classdef arduinoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InfoTextArea            matlab.ui.control.TextArea
        InfoTextArea_2          matlab.ui.control.TextArea
        STOPButton              matlab.ui.control.Button
        AARCOASISLabel          matlab.ui.control.Label
        DirectionKnobLabel      matlab.ui.control.Label
        DirectionKnob           matlab.ui.control.DiscreteKnob
        SpeedSliderLabel        matlab.ui.control.Label
        SpeedSlider             matlab.ui.control.Slider
        CurrentModeButtonGroup  matlab.ui.container.ButtonGroup
        AutomaticButton         matlab.ui.control.RadioButton
        ManualButton            matlab.ui.control.RadioButton
        ManualCommandsLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        ROP_Speed_Cmd
        ROP_Direction_Cmd
        Drilling_Mode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ROP_Speed_Cmd = 0; %move speed multiplier
            app.SpeedSlider.Value = 0;
            assignin('base','rop_speed_cmd', '0');
            
            app.DirectionKnob.Value = "Stop"; %stop mode
            app.ROP_Direction_Cmd = 0;
            assignin('base','rop_direction_cmd', '0');
            
            app.Drilling_Mode = '0'; %manual mode
            app.ManualButton.Value = 1;
            assignin('base','drilling_mode', '0');
         
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            value = app.DirectionKnob.Value; 
            if (value == "Up")
                app.ROP_Direction_Cmd = '-1';
            end
            if (value == "Stop")
                app.ROP_Direction_Cmd = '0';
            end
            if (value == "Down")
                app.ROP_Direction_Cmd = '1';
            end
            assignin('base','rop_direction_cmd', app.ROP_Direction_Cmd);
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            app.ROP_Speed_Cmd = value;
            assignin('base','rop_speed_cmd', num2str(app.ROP_Speed_Cmd));
        end

        % Callback function
        function AutonomousModeButtonPushed(app, event)
            
        end

        % Callback function
        function ManualModeButtonPushed(app, event)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            runStartupFcn(app, @startupFcn);
        end

        % Selection changed function: CurrentModeButtonGroup
        function CurrentModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.CurrentModeButtonGroup.SelectedObject;
            if (app.AutomaticButton.Value) 
                app.Drilling_Mode = '1'; %automatic mode
            elseif (app.ManualButton.Value)
                app.Drilling_Mode = '0'; %manual mode
            end
          
            assignin('base','drilling_mode', app.Drilling_Mode);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Value = {'Pressing Drilling Mode will start drilling, limiting WOB to 100N'};
            app.InfoTextArea_2 = uitextarea(app.UIFigure);
            app.InfoTextArea_2.Value = {'Pressing Manual Mode will allow user commands'};
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.AARCOASISLabel = uilabel(app.UIFigure);
            app.AARCOASISLabel.Text = 'AARC - OASIS';
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction Knob';
            app.DirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.DirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.Value = 'Stop';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed Slider (?/?)';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0 2];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.CurrentModeButtonGroup = uibuttongroup(app.UIFigure);
            app.CurrentModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CurrentModeButtonGroupSelectionChanged, true);
            app.CurrentModeButtonGroup.Title = 'Current Mode';
            app.AutomaticButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ManualCommandsLabel = uilabel(app.UIFigure);
            app.ManualCommandsLabel.Text = 'Manual Commands';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end