classdef arduinoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        STOPButton                   matlab.ui.control.Button
        DrillModeButtonGroup         matlab.ui.container.ButtonGroup
        AutomaticButton              matlab.ui.control.RadioButton
        ManualButton                 matlab.ui.control.RadioButton
        DrillCommandsLabel           matlab.ui.control.Label
        MirageCommandsLabel          matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        OtherCommandsLabel           matlab.ui.control.Label
        TareLoadCellButton           matlab.ui.control.Button
        DirectionKnobLabel           matlab.ui.control.Label
        DrillDirectionKnob           matlab.ui.control.DiscreteKnob
        ROPSlidermmsecLabel          matlab.ui.control.Label
        ROPSlidermmsec               matlab.ui.control.Slider
        WOBNEditFieldLabel           matlab.ui.control.Label
        WOBNEditField                matlab.ui.control.NumericEditField
        LimitSwitchReachedLampLabel  matlab.ui.control.Label
        LimitSwitchReachedLamp       matlab.ui.control.Lamp
        RPMSliderLabel               matlab.ui.control.Label
        RPMSlider                    matlab.ui.control.Slider
        DrillPosmmEditFieldLabel     matlab.ui.control.Label
        DrillPosmmEditField          matlab.ui.control.NumericEditField
        HeaterSwitchLabel            matlab.ui.control.Label
        HeaterSwitch                 matlab.ui.control.Switch
        DrillCurrentAEditFieldLabel  matlab.ui.control.Label
        DrillCurrentAEditField       matlab.ui.control.NumericEditField
        MiragePosdegEditFieldLabel   matlab.ui.control.Label
        MiragePosdegEditField        matlab.ui.control.NumericEditField
        DrillRPMEditFieldLabel       matlab.ui.control.Label
        DrillRPMEditField            matlab.ui.control.NumericEditField
        ZeroDrillSwitchLabel         matlab.ui.control.Label
        ZeroDrillSwitch              matlab.ui.control.Switch
        FakeLimitSwitchActiveLabel   matlab.ui.control.Label
        FakeLimitSwitchActive        matlab.ui.control.Switch
        DrillSwitchLabel             matlab.ui.control.Label
        DrillSwitch                  matlab.ui.control.Switch
        WOBSetpointSliderLabel       matlab.ui.control.Label
        WOBSetpointSlider            matlab.ui.control.Slider
        HeaterTempEditFieldLabel     matlab.ui.control.Label
        HeaterTempEditField          matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel    matlab.ui.control.Label
        HeaterPowerEditField         matlab.ui.control.NumericEditField
        MSEEditFieldLabel            matlab.ui.control.Label
        MSEEditField                 matlab.ui.control.NumericEditField
        ExtractionCommandsLabel      matlab.ui.control.Label
        ROPSlidermmsec_2Label        matlab.ui.control.Label
        ExtractionROPSlider          matlab.ui.control.Slider
        PumpCommandsLabel            matlab.ui.control.Label
        ROPSliderstepssecLabel       matlab.ui.control.Label
        PumpROPSlider                matlab.ui.control.Slider
        HeaterPowerSpinnerLabel      matlab.ui.control.Label
        HeaterPowerSpinner           matlab.ui.control.Spinner
        DirectionKnob_2Label         matlab.ui.control.Label
        ExtractionDirectionKnob      matlab.ui.control.DiscreteKnob
        DirectionKnob_2Label_2       matlab.ui.control.Label
        PumpDirectionKnob            matlab.ui.control.DiscreteKnob
        DirectionKnobLabel_2         matlab.ui.control.Label
        MirageDirectionKnob          matlab.ui.control.DiscreteKnob
        ROPSlidermmsec_2Label_2      matlab.ui.control.Label
        MirageSpeedSlider            matlab.ui.control.Slider
        IncomingDataLabel            matlab.ui.control.Label
        ZeroDrillButton              matlab.ui.control.Button
        ZeroExtractionButton         matlab.ui.control.Button
        DrillPowerSliderLabel        matlab.ui.control.Label
        DrillPowerSlider             matlab.ui.control.Slider
        IncomingDebugDataLabel_2     matlab.ui.control.Label
        SettingsTab                  matlab.ui.container.Tab
        KpSpinnerLabel               matlab.ui.control.Label
        KpSpinner                    matlab.ui.control.Spinner
        DrillSettingsLabel           matlab.ui.control.Label
        KiSpinnerLabel               matlab.ui.control.Label
        KiSpinner                    matlab.ui.control.Spinner
        KdSpinnerLabel               matlab.ui.control.Label
        KdSpinner                    matlab.ui.control.Spinner
        HeaterSettingsLabel          matlab.ui.control.Label
        KpSpinner_2Label             matlab.ui.control.Label
        KpSpinner_2                  matlab.ui.control.Spinner
        KiSpinner_2Label             matlab.ui.control.Label
        KiSpinner_2                  matlab.ui.control.Spinner
        KdSpinner_2Label             matlab.ui.control.Label
        KdSpinner_2                  matlab.ui.control.Spinner
        IncomingDebugDataLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        App_Closed
        ROP_Speed_Cmd
        ROP_Direction_Cmd
        Drilling_Mode
        Mirage_Speed_Cmd
        Mirage_Direction_Cmd
        RPMSpeed_Cmd
        Pump_Cmd
        Heater_Cmd
        Drill_Cmd
        Tare_Cmd
        DrillZero_Cmd
        FakeZero_Cmd %not used
        WOBsetpoint
        Kp_Drill
        Ki_Drill
        Kd_Drill
        Kp_Heater
        Ki_Heater
        Kd_Heater
        TemperatureSetpoint
        HeaterPowerSetpoint
        Extraction_ROP_Speed_Cmd
        Extraction_ROP_Direction_Cmd
        Pump_ROP_Speed_Cmd
        Pump_ROP_Direction_Cmd
        ExtractionZero_Cmd
        DrillPower
    end
    
    methods (Access = private)
        
        function reset(app)
            app.ROPSlidermmsec.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DrillDirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;

            app.ZeroDrillSwitch.Value = "Off";

            app.HeaterSwitch.Value = "Off";
            app.DrillSwitch.Value = "Off";
            app.Pump_Cmd = 0;
            app.Heater_Cmd = 0;
            app.Tare_Cmd = 0;
            app.DrillZero_Cmd = 0;
            app.FakeZero_Cmd = 0;
            app.Drill_Cmd = 0;
            
            app.WOBSetpointSlider.Value = 100;
            app.WOBsetpoint = 100;
            
            app.Kp_Drill = 8;
            app.Ki_Drill = 0;
            app.Kd_Drill = 0;
            app.Kp_Heater = 0.5;
            app.Ki_Heater = 0;
            app.Kd_Heater = 0;
            app.KpSpinner.Value = 8;
            app.KiSpinner.Value = 0;
            app.KdSpinner.Value = 0;
            app.KpSpinner_2.Value = 0.5;
            app.KiSpinner_2.Value = 0;
            app.KdSpinner_2.Value = 0;
  
            app.TemperatureSetpoint = 0;
            
            app.HeaterPowerSetpoint = 0;
            app.Extraction_ROP_Speed_Cmd = 0;
            app.Pump_ROP_Speed_Cmd = 0;
            
            app.HeaterPowerSpinner.Value = 0;
            app.ExtractionROPSlider.Value = 0;
            app.PumpROPSlider.Value = 0;
            
            app.Pump_ROP_Direction_Cmd = 0;
            app.Extraction_ROP_Direction_Cmd = 0;
            
            app.Mirage_Speed_Cmd = 0;
            app.MirageSpeedSlider.Value = 0;
            app.Mirage_Direction_Cmd = 1;
            app.MirageDirectionKnob.Value = "Stop";

            app.ExtractionZero_Cmd = 0;
            app.DrillPower = 0;
            app.DrillPowerSlider.Value = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %na
            app.App_Closed = 0;
            
            app.ROPSlidermmsec.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DrillDirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;
            
            app.HeaterSwitch.Value = "Off";
            app.DrillSwitch.Value = "Off";
            app.Pump_Cmd = 0;
            app.Heater_Cmd = 0;
            app.Tare_Cmd = 0;
            app.DrillZero_Cmd = 0;
            app.FakeZero_Cmd = 0;
            app.Drill_Cmd = 0;
            
            app.WOBSetpointSlider.Value = 100;
            app.WOBsetpoint = 100;
            
            app.Kp_Drill = 8;
            app.Ki_Drill = 0;
            app.Kd_Drill = 0;
            app.Kp_Heater = 0.5;
            app.Ki_Heater = 0;
            app.Kd_Heater = 0;
            app.KpSpinner.Value = 8;
            app.KiSpinner.Value = 0;
            app.KdSpinner.Value = 0;
            app.KpSpinner_2.Value = 0.5;
            app.KiSpinner_2.Value = 0;
            app.KdSpinner_2.Value = 0;
            
            app.TemperatureSetpoint = 0;
            
            app.HeaterPowerSetpoint = 0;
            app.Extraction_ROP_Speed_Cmd = 0;
            app.Pump_ROP_Speed_Cmd = 0;
            
            app.HeaterPowerSpinner.Value = 0;
            app.ExtractionROPSlider.Value = 0;
            app.PumpROPSlider.Value = 0;
            
            app.Pump_ROP_Direction_Cmd = 0;
            app.Extraction_ROP_Direction_Cmd = 0;
            app.ExtractionDirectionKnob.Value = "Stop"; % stop mode
            app.PumpDirectionKnob.Value = "Stop"; % stop mode
            
            app.Mirage_Speed_Cmd = 0;
            app.MirageSpeedSlider.Value = 0;
            app.Mirage_Direction_Cmd = 1;
            app.MirageDirectionKnob.Value = "Stop";

            app.ExtractionZero_Cmd = 0;
            app.DrillPower = 0;
            app.DrillPowerSlider.Value = 0;
        end

        % Value changed function: DrillDirectionKnob
        function DrillDirectionKnobValueChanged(app, event)
            value = app.DrillDirectionKnob.Value; 
            if (value == "Up")
                app.ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.ROP_Direction_Cmd = 1;
            end
        end

        % Value changed function: ROPSlidermmsec
        function ROPSlidermmsecValueChanged(app, event)
            value = app.ROPSlidermmsec.Value;
            app.ROP_Speed_Cmd = int16(value);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                reset(app);
        end

        % Selection changed function: DrillModeButtonGroup
        function DrillModeButtonGroupSelectionChanged(app, event)
            if (app.AutomaticButton.Value) 
                app.Drilling_Mode = 0; %automatic mode
            elseif (app.ManualButton.Value)
                app.Drilling_Mode = 1; %manual mode
            end
        end

        % Value changing function: ROPSlidermmsec
        function ROPSlidermmsecValueChanging(app, event)
            changingValue = event.Value;
            app.ROP_Speed_Cmd = int16(changingValue);
        end

        % Callback function
        function MiragePositionKnobValueChanged(app, event)
            value = app.MiragePositionKnob.Value;
            app.Mirage_Position_Cmd = str2double(value); 
            app.PositonSpinner.Value = str2double(value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            reset(app); 
            delete(app); 
        end

        % Value changed function: RPMSlider
        function RPMSliderValueChanged(app, event)
            value = app.RPMSlider.Value;
            app.RPMSpeed_Cmd = int16(value);
        end

        % Callback function
        function PositonSpinnerValueChanged(app, event)
            value = app.PositonSpinner.Value;
            app.Mirage_Position_Cmd = value;
            app.MiragePositionKnob.Value = int2str(idivide(value,int16(30))*30);
        end

        % Value changed function: HeaterSwitch
        function HeaterSwitchValueChanged(app, event)
            value = app.HeaterSwitch.Value;
            if (value == "On")
                app.Heater_Cmd = 1;
            else
                app.Heater_Cmd = 0;
            end
        end

        % Callback function
        function PumpSwitchValueChanged(app, event)
            value = app.PumpSwitch.Value;
            if (value == "On")
                app.Pump_Cmd = 1;
            else
                app.Pump_Cmd = 0;
            end
        end

        % Button pushed function: TareLoadCellButton
        function TareLoadCellButtonPushed(app, event)
            app.Tare_Cmd = 1;
        end

        % Value changing function: RPMSlider
        function RPMSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RPMSpeed_Cmd = int16(changingValue);
        end

        % Value changed function: ZeroDrillSwitch
        function ZeroDrillSwitchValueChanged(app, event)
            value = app.ZeroDrillSwitch.Value;
            if (value == "On")
                app.DrillZero_Cmd = 1;
            else
                app.DrillZero_Cmd = 0;
            end
        end

        % Value changed function: FakeLimitSwitchActive
        function FakeLimitSwitchActiveValueChanged(app, event)
            value = app.FakeLimitSwitchActive.Value;
            if (value == "On")
                app.FakeZero_Cmd = 1;
            else
                app.FakeZero_Cmd = 0;
            end
        end

        % Value changed function: DrillSwitch
        function DrillSwitchValueChanged(app, event)
            value = app.DrillSwitch.Value;
            if (value == "On")
                app.Drill_Cmd = 1;
            else
                app.Drill_Cmd = 0;
            end
        end

        % Value changing function: WOBSetpointSlider
        function WOBSetpointSliderValueChanging(app, event)
            changingValue = event.Value;
            app.WOBsetpoint = int16(changingValue);
        end

        % Value changed function: KpSpinner_2
        function KpSpinner_2ValueChanged(app, event)
            value = app.KpSpinner_2.Value * 100; %so that we can send floats as "int", divide by 100 on arduino side
            app.Kp_Heater = int16(value);
        end

        % Value changed function: KiSpinner_2
        function KiSpinner_2ValueChanged(app, event)
            value = app.KiSpinner_2.Value * 100;
            app.Ki_Heater = int16(value);
        end

        % Value changed function: KdSpinner_2
        function KdSpinner_2ValueChanged(app, event)
            value = app.KdSpinner_2.Value * 100;
            app.Kd_Heater = int16(value);
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            value = app.KpSpinner.Value;
            app.Kp_Drill = int16(value);
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            value = app.KiSpinner.Value;
            app.Ki_Drill = int16(value);
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            value = app.KdSpinner.Value;
            app.Kd_Drill = int16(value);
        end

        % Value changed function: HeaterPowerSpinner
        function HeaterPowerSpinnerValueChanged(app, event)
            value = app.HeaterPowerSpinner.Value;
            app.HeaterPowerSetpoint = int16(value);
        end

        % Value changing function: ExtractionROPSlider
        function ExtractionROPSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Extraction_ROP_Speed_Cmd = int16(changingValue);
        end

        % Value changing function: PumpROPSlider
        function PumpROPSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Pump_ROP_Speed_Cmd = int16(changingValue);
        end

        % Value changed function: PumpDirectionKnob
        function PumpDirectionKnobValueChanged(app, event)
            value = app.PumpDirectionKnob.Value;
            if (value == "Up")
                app.Pump_ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.Pump_ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.Pump_ROP_Direction_Cmd = 1;
            end
        end

        % Value changed function: ExtractionDirectionKnob
        function ExtractionDirectionKnobValueChanged(app, event)
            value = app.ExtractionDirectionKnob.Value;
            if (value == "Up")
                app.Extraction_ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.Extraction_ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.Extraction_ROP_Direction_Cmd = 1;
            end
        end

        % Value changed function: MirageDirectionKnob
        function MirageDirectionKnobValueChanged(app, event)
            value = app.MirageDirectionKnob.Value;
            if (value == "Up")
                app.Mirage_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.Mirage_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.Mirage_Direction_Cmd = 1;
            end
        end

        % Value changing function: MirageSpeedSlider
        function MirageSpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Mirage_Speed_Cmd = int16(changingValue);
        end

        % Button pushed function: ZeroDrillButton
        function ZeroDrillButtonPushed(app, event)
            if (value == "On")
                app.DrillZero_Cmd = 1;
            else
                app.DrillZero_Cmd = 0;
            end
        end

        % Button pushed function: ZeroExtractionButton
        function ZeroExtractionButtonPushed(app, event)
            if (value == "On")
                app.ExtractionZero_Cmd = 1;
            else
                app.ExtractionZero_Cmd = 0;
            end
        end

        % Value changing function: DrillPowerSlider
        function DrillPowerSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DrillPower = int16(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.STOPButton = uibutton(app.HomeTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DrillModeButtonGroup = uibuttongroup(app.HomeTab);
            app.DrillModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrillModeButtonGroupSelectionChanged, true);
            app.DrillModeButtonGroup.Title = 'Drill Mode';
            app.DrillModeButtonGroup.Visible = 'off';
            app.AutomaticButton = uiradiobutton(app.DrillModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uiradiobutton(app.DrillModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.DrillCommandsLabel = uilabel(app.HomeTab);
            app.DrillCommandsLabel.Text = 'Drill Commands';
            app.MirageCommandsLabel = uilabel(app.HomeTab);
            app.MirageCommandsLabel.Text = 'Mirage Commands';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'AARC5-05.png';
            app.OtherCommandsLabel = uilabel(app.HomeTab);
            app.OtherCommandsLabel.Text = 'Other Commands';
            app.TareLoadCellButton = uibutton(app.HomeTab, 'push');
            app.TareLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @TareLoadCellButtonPushed, true);
            app.TareLoadCellButton.Text = 'Tare Load Cell';
            app.DirectionKnobLabel = uilabel(app.HomeTab);
            app.DirectionKnobLabel.Text = 'Direction Knob';
            app.DrillDirectionKnob = uiknob(app.HomeTab, 'discrete');
            app.DrillDirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DrillDirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DrillDirectionKnobValueChanged, true);
            app.DrillDirectionKnob.Value = 'Stop';
            app.ROPSlidermmsecLabel = uilabel(app.HomeTab);
            app.ROPSlidermmsecLabel.Text = 'ROP Slider (mm/sec)';
            app.ROPSlidermmsec = uislider(app.HomeTab);
            app.ROPSlidermmsec.Limits = [0 40];
            app.ROPSlidermmsec.ValueChangedFcn = createCallbackFcn(app, @ROPSlidermmsecValueChanged, true);
            app.ROPSlidermmsec.ValueChangingFcn = createCallbackFcn(app, @ROPSlidermmsecValueChanging, true);
            app.WOBNEditFieldLabel = uilabel(app.HomeTab);
            app.WOBNEditFieldLabel.Visible = 'off';
            app.WOBNEditFieldLabel.Text = 'WOB (N)';
            app.WOBNEditField = uieditfield(app.HomeTab, 'numeric');
            app.WOBNEditField.Visible = 'off';
            app.LimitSwitchReachedLampLabel = uilabel(app.HomeTab);
            app.LimitSwitchReachedLampLabel.Visible = 'off';
            app.LimitSwitchReachedLampLabel.Text = 'Limit Switch Reached';
            app.LimitSwitchReachedLamp = uilamp(app.HomeTab);
            app.LimitSwitchReachedLamp.Visible = 'off';
            app.RPMSliderLabel = uilabel(app.HomeTab);
            app.RPMSliderLabel.Visible = 'off';
            app.RPMSliderLabel.Text = 'RPM Slider';
            app.RPMSlider = uislider(app.HomeTab);
            app.RPMSlider.Limits = [0 1000];
            app.RPMSlider.ValueChangedFcn = createCallbackFcn(app, @RPMSliderValueChanged, true);
            app.RPMSlider.ValueChangingFcn = createCallbackFcn(app, @RPMSliderValueChanging, true);
            app.RPMSlider.Visible = 'off';
            app.DrillPosmmEditFieldLabel = uilabel(app.HomeTab);
            app.DrillPosmmEditFieldLabel.Visible = 'off';
            app.DrillPosmmEditFieldLabel.Text = 'Drill Pos (mm)';
            app.DrillPosmmEditField = uieditfield(app.HomeTab, 'numeric');
            app.DrillPosmmEditField.Visible = 'off';
            app.HeaterSwitchLabel = uilabel(app.HomeTab);
            app.HeaterSwitchLabel.Text = 'Heater';
            app.HeaterSwitch = uiswitch(app.HomeTab, 'slider');
            app.HeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterSwitchValueChanged, true);
            app.DrillCurrentAEditFieldLabel = uilabel(app.HomeTab);
            app.DrillCurrentAEditFieldLabel.Visible = 'off';
            app.DrillCurrentAEditFieldLabel.Text = 'Drill Current (A)';
            app.DrillCurrentAEditField = uieditfield(app.HomeTab, 'numeric');
            app.DrillCurrentAEditField.Visible = 'off';
            app.MiragePosdegEditFieldLabel = uilabel(app.HomeTab);
            app.MiragePosdegEditFieldLabel.Visible = 'off';
            app.MiragePosdegEditFieldLabel.Text = 'Mirage Pos (deg)';
            app.MiragePosdegEditField = uieditfield(app.HomeTab, 'numeric');
            app.MiragePosdegEditField.Visible = 'off';
            app.DrillRPMEditFieldLabel = uilabel(app.HomeTab);
            app.DrillRPMEditFieldLabel.Visible = 'off';
            app.DrillRPMEditFieldLabel.Text = 'Drill RPM';
            app.DrillRPMEditField = uieditfield(app.HomeTab, 'numeric');
            app.DrillRPMEditField.Visible = 'off';
            app.ZeroDrillSwitchLabel = uilabel(app.HomeTab);
            app.ZeroDrillSwitchLabel.Visible = 'off';
            app.ZeroDrillSwitchLabel.Text = 'Zero Drill';
            app.ZeroDrillSwitch = uiswitch(app.HomeTab, 'slider');
            app.ZeroDrillSwitch.ValueChangedFcn = createCallbackFcn(app, @ZeroDrillSwitchValueChanged, true);
            app.ZeroDrillSwitch.Visible = 'off';
            app.FakeLimitSwitchActiveLabel = uilabel(app.HomeTab);
            app.FakeLimitSwitchActiveLabel.Visible = 'off';
            app.FakeLimitSwitchActiveLabel.Text = 'Fake Limit Switch Active';
            app.FakeLimitSwitchActive = uiswitch(app.HomeTab, 'slider');
            app.FakeLimitSwitchActive.ValueChangedFcn = createCallbackFcn(app, @FakeLimitSwitchActiveValueChanged, true);
            app.FakeLimitSwitchActive.Visible = 'off';
            app.DrillSwitchLabel = uilabel(app.HomeTab);
            app.DrillSwitchLabel.Text = 'Drill';
            app.DrillSwitch = uiswitch(app.HomeTab, 'slider');
            app.DrillSwitch.ValueChangedFcn = createCallbackFcn(app, @DrillSwitchValueChanged, true);
            app.WOBSetpointSliderLabel = uilabel(app.HomeTab);
            app.WOBSetpointSliderLabel.Visible = 'off';
            app.WOBSetpointSliderLabel.Text = 'WOB Setpoint';
            app.WOBSetpointSlider = uislider(app.HomeTab);
            app.WOBSetpointSlider.ValueChangingFcn = createCallbackFcn(app, @WOBSetpointSliderValueChanging, true);
            app.WOBSetpointSlider.Visible = 'off';
            app.HeaterTempEditFieldLabel = uilabel(app.HomeTab);
            app.HeaterTempEditFieldLabel.Visible = 'off';
            app.HeaterTempEditFieldLabel.Text = 'Heater Temp';
            app.HeaterTempEditField = uieditfield(app.HomeTab, 'numeric');
            app.HeaterTempEditField.Visible = 'off';
            app.HeaterPowerEditFieldLabel = uilabel(app.HomeTab);
            app.HeaterPowerEditFieldLabel.Visible = 'off';
            app.HeaterPowerEditFieldLabel.Text = 'Heater Power';
            app.HeaterPowerEditField = uieditfield(app.HomeTab, 'numeric');
            app.HeaterPowerEditField.Visible = 'off';
            app.MSEEditFieldLabel = uilabel(app.HomeTab);
            app.MSEEditFieldLabel.Visible = 'off';
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.HomeTab, 'numeric');
            app.MSEEditField.Visible = 'off';
            app.ExtractionCommandsLabel = uilabel(app.HomeTab);
            app.ExtractionCommandsLabel.Text = 'Extraction Commands';
            app.ROPSlidermmsec_2Label = uilabel(app.HomeTab);
            app.ROPSlidermmsec_2Label.Text = 'ROP Slider (mm/sec)';
            app.ExtractionROPSlider = uislider(app.HomeTab);
            app.ExtractionROPSlider.Limits = [0 40];
            app.ExtractionROPSlider.ValueChangingFcn = createCallbackFcn(app, @ExtractionROPSliderValueChanging, true);
            app.PumpCommandsLabel = uilabel(app.HomeTab);
            app.PumpCommandsLabel.Text = 'Pump Commands';
            app.ROPSliderstepssecLabel = uilabel(app.HomeTab);
            app.ROPSliderstepssecLabel.Text = 'ROP Slider (steps/sec)';
            app.PumpROPSlider = uislider(app.HomeTab);
            app.PumpROPSlider.Limits = [0 800];
            app.PumpROPSlider.ValueChangingFcn = createCallbackFcn(app, @PumpROPSliderValueChanging, true);
            app.HeaterPowerSpinnerLabel = uilabel(app.HomeTab);
            app.HeaterPowerSpinnerLabel.Text = 'Heater Power (%)';
            app.HeaterPowerSpinner = uispinner(app.HomeTab);
            app.HeaterPowerSpinner.Limits = [0 500];
            app.HeaterPowerSpinner.ValueChangedFcn = createCallbackFcn(app, @HeaterPowerSpinnerValueChanged, true);
            app.DirectionKnob_2Label = uilabel(app.HomeTab);
            app.DirectionKnob_2Label.Text = 'Direction Knob';
            app.ExtractionDirectionKnob = uiknob(app.HomeTab, 'discrete');
            app.ExtractionDirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.ExtractionDirectionKnob.ValueChangedFcn = createCallbackFcn(app, @ExtractionDirectionKnobValueChanged, true);
            app.ExtractionDirectionKnob.Value = 'Stop';
            app.DirectionKnob_2Label_2 = uilabel(app.HomeTab);
            app.DirectionKnob_2Label_2.Text = 'Direction Knob';
            app.PumpDirectionKnob = uiknob(app.HomeTab, 'discrete');
            app.PumpDirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.PumpDirectionKnob.ValueChangedFcn = createCallbackFcn(app, @PumpDirectionKnobValueChanged, true);
            app.PumpDirectionKnob.Value = 'Stop';
            app.DirectionKnobLabel_2 = uilabel(app.HomeTab);
            app.DirectionKnobLabel_2.Text = 'Direction Knob';
            app.MirageDirectionKnob = uiknob(app.HomeTab, 'discrete');
            app.MirageDirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.MirageDirectionKnob.ValueChangedFcn = createCallbackFcn(app, @MirageDirectionKnobValueChanged, true);
            app.MirageDirectionKnob.Value = 'Stop';
            app.ROPSlidermmsec_2Label_2 = uilabel(app.HomeTab);
            app.ROPSlidermmsec_2Label_2.Text = 'ROP Slider (mm/sec)';
            app.MirageSpeedSlider = uislider(app.HomeTab);
            app.MirageSpeedSlider.Limits = [0 40];
            app.MirageSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @MirageSpeedSliderValueChanging, true);
            app.IncomingDataLabel = uilabel(app.HomeTab);
            app.IncomingDataLabel.Text = 'Incoming Data';
            app.ZeroDrillButton = uibutton(app.HomeTab, 'push');
            app.ZeroDrillButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroDrillButtonPushed, true);
            app.ZeroDrillButton.Text = 'Zero Drill';
            app.ZeroExtractionButton = uibutton(app.HomeTab, 'push');
            app.ZeroExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroExtractionButtonPushed, true);
            app.ZeroExtractionButton.Text = 'Zero Extraction';
            app.DrillPowerSliderLabel = uilabel(app.HomeTab);
            app.DrillPowerSliderLabel.Text = 'Drill Power Slider ';
            app.DrillPowerSlider = uislider(app.HomeTab);
            app.DrillPowerSlider.Limits = [0 180];
            app.DrillPowerSlider.ValueChangingFcn = createCallbackFcn(app, @DrillPowerSliderValueChanging, true);
            app.IncomingDebugDataLabel_2 = uilabel(app.HomeTab);
            app.IncomingDebugDataLabel_2.Visible = 'off';
            app.IncomingDebugDataLabel_2.Text = 'Incoming Data';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.KpSpinnerLabel = uilabel(app.SettingsTab);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.SettingsTab);
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.DrillSettingsLabel = uilabel(app.SettingsTab);
            app.DrillSettingsLabel.Text = 'Drill Settings';
            app.KiSpinnerLabel = uilabel(app.SettingsTab);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.SettingsTab);
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.SettingsTab);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.SettingsTab);
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.HeaterSettingsLabel = uilabel(app.SettingsTab);
            app.HeaterSettingsLabel.Text = 'Heater Settings';
            app.KpSpinner_2Label = uilabel(app.SettingsTab);
            app.KpSpinner_2Label.Text = 'Kp';
            app.KpSpinner_2 = uispinner(app.SettingsTab);
            app.KpSpinner_2.Step = 0.05;
            app.KpSpinner_2.Limits = [0 0.5];
            app.KpSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KpSpinner_2ValueChanged, true);
            app.KiSpinner_2Label = uilabel(app.SettingsTab);
            app.KiSpinner_2Label.Text = 'Ki';
            app.KiSpinner_2 = uispinner(app.SettingsTab);
            app.KiSpinner_2.Step = 0.05;
            app.KiSpinner_2.Limits = [0 0.5];
            app.KiSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KiSpinner_2ValueChanged, true);
            app.KdSpinner_2Label = uilabel(app.SettingsTab);
            app.KdSpinner_2Label.Text = 'Kd';
            app.KdSpinner_2 = uispinner(app.SettingsTab);
            app.KdSpinner_2.Step = 0.05;
            app.KdSpinner_2.Limits = [0 0.5];
            app.KdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KdSpinner_2ValueChanged, true);
            app.IncomingDebugDataLabel = uilabel(app.SettingsTab);
            app.IncomingDebugDataLabel.Text = 'Incoming Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end